classdef waterSamplerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainMenu     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Image        matlab.ui.control.Image
        LogsText     matlab.ui.control.TextArea
        LogsPanel    matlab.ui.container.Panel
        WebcamPanel  matlab.ui.container.Panel
        StopButton   matlab.ui.control.Button
        SCRAMButton  matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.MainMenu.WindowState = 'maximized';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.GridLayout.BackgroundColor = [0.45, 0.96, 0.09];
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.GridLayout.BackgroundColor = [0.00, 0.34, 0.56];
        end

        % Button pushed function: SCRAMButton
        function SCRAMButtonPushed(app, event)
            app.GridLayout.BackgroundColor = [0.80, 0.00, 0.00];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainMenu = uifigure('Visible', 'off');
            app.MainMenu.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.MainMenu);
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Starts the water sampler'};
            app.StartButton.Text = 'Start';
            app.SCRAMButton = uibutton(app.GridLayout, 'push');
            app.SCRAMButton.ButtonPushedFcn = createCallbackFcn(app, @SCRAMButtonPushed, true);
            app.SCRAMButton.Tooltip = {'Stops the water sampler instantly without resetting motor positions'};
            app.SCRAMButton.Text = 'SCRAM';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tooltip = {'Stops the water sampler and resets to default position'};
            app.StopButton.Text = 'Stop';
            app.WebcamPanel = uipanel(app.GridLayout);
            app.WebcamPanel.Title = 'Webcam';
            app.LogsPanel = uipanel(app.GridLayout);
            app.LogsPanel.Title = 'Logs';
            app.LogsText = uitextarea(app.GridLayout);
            app.LogsText.Editable = 'off';
            app.Image = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.MainMenu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waterSamplerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainMenu)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainMenu)
        end
    end
end